classdef App1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure           % UI Figure
        browse      matlab.ui.control.Button   % Browse
        Label       matlab.ui.control.Label   
        val         matlab.ui.control.ListBox 
        comp        matlab.ui.control.Button   % Compress
        decomp      matlab.ui.control.Button   % Decompress
        outText     matlab.ui.control.TextArea
        Label2      matlab.ui.control.Label    % OUTPUT:
        Label3      matlab.ui.control.Label   
        Label4      matlab.ui.control.Label    % FileLength:
        Label5      matlab.ui.control.Label    % CompressedLength
        LabelSlider matlab.ui.control.Label    % Adress Bits
        addrSlider  matlab.ui.control.Slider   % [9 12]
        Label6      matlab.ui.control.Label    % 9
        Label7      matlab.ui.control.Label    % Compression:
        Label8      matlab.ui.control.Label   
    end


    properties (Access = private)
        %counters
        x=1;
        dictCounter=256;
        d=1;
        
        %files
        fileID;
        a;
        filename='';
        pathname='';
        
        %algo
        p;
        c;
        string;
        output;
        dict={};
        ce={};
        addressLength=9;
        fileLength;
        compressedLength;
        
        compression=0;
    end

    methods (Access = private)
        
        function Init(app,comp)
            app.x=1;
            app.dict={};
            app.ce={};
            app.output=[];
            app.dictCounter=256;
            app.d=1;
            app.addressLength = floor(double(app.addrSlider.Value));

            if strcmp(comp,'compress')
                app.p=app.a(app.x);
                app.c=app.a(app.x+1);
                app.string =[app.p,app.c];
                app.output=[app.output,dec2bin(double(app.p),app.addressLength)];
            else
                app.p=char(app.a(app.x));
                app.c=char(app.a(app.x+1));
                app.string =[app.p,app.c];
                app.output=[app.output,app.p];
            end
            
           
            app.dict{app.d,1} = app.dictCounter;
            app.dict{app.d,2} = app.string;
            app.dictCounter= app.dictCounter+1;
            app.newDictShow();
            app.x=app.x+1;
            app.d=app.d+1;
            app.p=app.c;
        end

        function newDictShow(app)
            app.val.Items{end+1} = [num2str(app.dict{app.d,1}),'    ',app.dict{app.d,2}];
        end

        function [bool,index] = inDictionary(app,str)
            if size(app.dict,1)>1
                for i=1:size(app.dict,1)
                    if strcmp(str,app.dict{i,2})
                        bool=1;
                        index=app.dict{i,1};
                        break;
                    end
                end
                if i==size(app.dict,1)
                    bool=0;
                    index=-1;
                end
            else
               if strcmp(str,app.dict{1,2})
                        bool=1;
                        index=app.dict{1,1};
               else
                   bool=0;
                   index=-1;
               end
            end
        end

        function clearContents(app)
            app.outText.Value='';
            drawnow
            app.val.Items={};
            drawnow
            app.Label8.Text='';
            drawnow;
        end

    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;
        end

        % browse button pushed function
        function browseButtonPushed(app)
            app.clearContents();
            [app.filename,app.pathname] = uigetfile({'*.txt'},'Choose File');
            app.Label.Text = strcat(app.pathname,app.filename);
            drawnow
            
            app.addrSlider.Enable = 'on';
            app.LabelSlider.Enable='on';
            app.Label6.Enable='on';
        end

        % comp button pushed function
        function compButtonPushed(app)
            app.clearContents();
            if strcmp(app.filename,'')
                app.Label3.Text ='ERROR: FILE NOT CHOSEN';
            else

                app.addrSlider.Enable = 'off';
                app.LabelSlider.Enable='off';
                app.Label6.Enable='off';
                app.Label3.Text ='';
                app.Label7.Visible='on';
                app.Label8.Visible='on';

                
                app.fileID = fopen(app.Label.Text,'r');
                app.a = fscanf(app.fileID,'%s');
                fclose(app.fileID);
                app.fileLength = length(app.a)*8;
                app.Label4.Text = ['FileLength: ', num2str(app.fileLength), ' bits'];
                app.Init('compress');

                while app.x<length(app.a)
                    app.c=app.a(app.x+1);    
                    app.string=[app.p,app.c];
                    [bool,~] = app.inDictionary(app.string);
                    if bool
                        app.p=app.string;
                    else
                        if length(app.p)==1
                            app.output=[app.output,dec2bin(double(app.p),app.addressLength)];
                        else
                            [~,index] = app.inDictionary(app.p);
                            app.output=[app.output,dec2bin(double(index),app.addressLength)];
                        end
                        
                        if app.dictCounter ~= 2^app.addressLength
                            app.dict{app.d,1} = app.dictCounter;
                            app.dict{app.d,2} = app.string;
                            app.dictCounter = app.dictCounter+1;
                            app.newDictShow();
                            app.d=app.d+1;
                        end
                        app.p=app.c;
                    end
                    app.x=app.x+1;
                end
                
                if length(app.p)==1
                    app.output=[app.output,dec2bin(double(app.p),app.addressLength)];
                else
                    [~,index] = app.inDictionary(app.p);
                    app.output=[app.output,dec2bin(double(index),app.addressLength)];
                end
                for i=1:app.addressLength:length(app.output)
                    app.ce{floor(i/app.addressLength)+1} = ['Binary: ',app.output(i:(i+app.addressLength-1)),'    Decimal: ',num2str(bin2dec(app.output(i:(i+app.addressLength-1))))];
                end
                app.compressedLength = length(app.output);
                app.Label5.Text = ['CompressedLength: ',num2str(app.compressedLength), ' bits'];
                app.outText.Value=[app.outText.Value,app.ce];
                
                app.fileID = fopen('output.txt','w');
                for i=1:app.addressLength:length(app.output)
                    fprintf(app.fileID,'%s\n',app.output(i:i+app.addressLength-1));
                    fprintf(app.fileID,'\n');
                end
                fclose(app.fileID);
                
                app.compression = app.compressedLength/app.fileLength;
                if app.compression<1
                    app.Label8.FontColor = [0,1,0];
                else
                    app.Label8.FontColor = [1,0,0];                    
                end
                app.Label8.Text = [num2str(app.compression),' %'];
                
                app.addrSlider.Enable = 'on';
                app.LabelSlider.Enable='on';
                app.Label6.Enable='on';
             end
        end

        % addrSlider value changed function
        function addrSliderValueChanged(app)
            app.addressLength = floor(double(app.addrSlider.Value));
            app.addrSlider.Value = app.addressLength;
            app.Label6.Text = num2str(app.addressLength);
            drawnow
            
        end

        % decomp button pushed function
        function decompButtonPushed(app)
             app.clearContents();
            if strcmp(app.filename,'')
                app.Label3.Text ='ERROR: FILE NOT CHOSEN';
            else
                app.addrSlider.Enable = 'off';
                app.LabelSlider.Enable='off';
                app.Label6.Enable='off';
                app.Label3.Text =''; 
                app.Label7.Visible='off';
                app.Label8.Visible='off';
                
                app.fileID = fopen(app.Label.Text,'r');
                ident=['%',num2str(app.addressLength),'lu'];
                disp(ident);
                app.a = bin2dec(num2str(fscanf(app.fileID,ident)));
                fclose(app.fileID);
                
                app.Label4.Text = ['FileLength: ', num2str(length(app.a)), ' symbols'];
                
                app.Init('decompress');
                
                while app.x<length(app.a)
                    if app.a(app.x+1)<255
                        app.c= char(app.a(app.x+1));
                    else
                        app.c=app.dict{app.a(app.x+1)-255,2};
                    end
                    app.string = [app.p,app.c(1)];
                    [bool,~] = app.inDictionary(app.string);
                    if bool
                        app.p=app.string;
                    else
                        app.output = [app.output,app.p];
                        app.dict{app.d,1} = app.dictCounter;
                        app.dict{app.d,2} = app.string;
                        app.newDictShow();
                        app.dictCounter = app.dictCounter+1;
                        app.d=app.d+1;
                        app.p=app.c;
                    end
                    app.x=app.x+1;
                end  
                app.output=[app.output,app.p];
                app.compressedLength = length(app.output);
                app.outText.Value = [app.outText.Value,app.output];
                
                app.Label5.Text = ['DecompressedLength: ',num2str(app.compressedLength), ' symbols'];


                app.addrSlider.Enable = 'on';
                app.LabelSlider.Enable='on';
                app.Label6.Enable='on';
                
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.browse = uibutton(app.UIFigure, 'push');
            app.browse.ButtonPushedFcn = createCallbackFcn(app, @browseButtonPushed);
            app.browse.Text = 'Browse';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.val = uilistbox(app.UIFigure);
            app.val.Items = {};
            app.val.Value = {};
            app.comp = uibutton(app.UIFigure, 'push');
            app.comp.ButtonPushedFcn = createCallbackFcn(app, @compButtonPushed);
            app.comp.Text = 'Compress';
            app.decomp = uibutton(app.UIFigure, 'push');
            app.decomp.ButtonPushedFcn = createCallbackFcn(app, @decompButtonPushed);
            app.decomp.Text = 'Decompress';
            app.outText = uitextarea(app.UIFigure);
            app.outText.Editable = 'off';
            app.Label2 = uilabel(app.UIFigure);
            app.Label2.Text = 'OUTPUT:';
            app.Label3 = uilabel(app.UIFigure);
            app.Label3.Text = '';
            app.Label4 = uilabel(app.UIFigure);
            app.Label4.Text = 'FileLength:';
            app.Label5 = uilabel(app.UIFigure);
            app.Label5.Text = 'CompressedLength';
            app.LabelSlider = uilabel(app.UIFigure);
            app.LabelSlider.Enable = 'off';
            app.LabelSlider.Text = 'Adress Bits';
            app.addrSlider = uislider(app.UIFigure);
            app.addrSlider.Limits = [9 12];
            app.addrSlider.MajorTicks = [9 10 11 12];
            app.addrSlider.ValueChangedFcn = createCallbackFcn(app, @addrSliderValueChanged);
            app.addrSlider.MinorTicks = [];
            app.addrSlider.Enable = 'off';
            app.addrSlider.Value = 9;
            app.Label6 = uilabel(app.UIFigure);
            app.Label6.Enable = 'off';
            app.Label6.Text = '9';
            app.Label7 = uilabel(app.UIFigure);
            app.Label7.Text = 'Compression:';
            app.Label8 = uilabel(app.UIFigure);
            app.Label8.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = App1()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end